classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        runButton               matlab.ui.control.Button
        joint1SliderLabel       matlab.ui.control.Label
        joint1Slider            matlab.ui.control.Slider
        joint2SliderLabel       matlab.ui.control.Label
        joint2Slider            matlab.ui.control.Slider
        joint3SliderLabel       matlab.ui.control.Label
        joint3Slider            matlab.ui.control.Slider
        joint4SliderLabel       matlab.ui.control.Label
        joint4Slider            matlab.ui.control.Slider
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.EditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.EditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.EditField
        Joint1EditFieldLabel    matlab.ui.control.Label
        Joint1EditField         matlab.ui.control.NumericEditField
        Joint2EditFieldLabel    matlab.ui.control.Label
        Joint2EditField         matlab.ui.control.NumericEditField
        Joint3EditFieldLabel    matlab.ui.control.Label
        Joint3EditField         matlab.ui.control.NumericEditField
        Joint4EditFieldLabel    matlab.ui.control.Label
        Joint4EditField         matlab.ui.control.NumericEditField
        FeedBackLabel           matlab.ui.control.Label
        SetPointLabel           matlab.ui.control.Label
        XEditField_2Label       matlab.ui.control.Label
        XEditField_2            matlab.ui.control.EditField
        YEditField_2Label       matlab.ui.control.Label
        YEditField_2            matlab.ui.control.EditField
        ZEditField_2Label       matlab.ui.control.Label
        ZEditField_2            matlab.ui.control.EditField
        Joint1EditField_2Label  matlab.ui.control.Label
        Joint1EditField_2       matlab.ui.control.NumericEditField
        Joint2EditField_2Label  matlab.ui.control.Label
        Joint2EditField_2       matlab.ui.control.NumericEditField
        Joint3EditField_2Label  matlab.ui.control.Label
        Joint3EditField_2       matlab.ui.control.NumericEditField
        Joint4EditField_2Label  matlab.ui.control.Label
        Joint4EditField_2       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            joint1_SP = app.joint1Slider.Value ;
            joint2_SP = app.joint2Slider.Value ;
            joint3_SP = app.joint3Slider.Value ;
            joint4_SP = app.joint4Slider.Value ;
            app.Joint1EditField_2.Value = joint1_SP;
            app.Joint2EditField_2.Value = joint2_SP;
            app.Joint3EditField_2.Value = joint3_SP;
            app.Joint4EditField_2.Value = joint4_SP;
            
            Xset_Point = app.XEditField.Value ;
            Xset_Point = str2num(Xset_Point);
            Yset_Point = app.YEditField.Value ;
            Yset_Point = str2num(Yset_Point);
            Zset_Point = app.ZEditField.Value ;
            Zset_Point = str2num(Zset_Point);
            mdl = 'Aluminum_Robot_ARM' ;
            open_system(mdl);
            in = Simulink.SimulationInput(mdl);
            in = in.setVariable('joint1_SP',joint1_SP);
            in = in.setVariable('joint2_SP',joint2_SP);
            in = in.setVariable('joint3_SP',joint3_SP);
            in = in.setVariable('joint4_SP',joint4_SP);
            in = in.setVariable('Xset_Point',Xset_Point);
            in = in.setVariable('Yset_Point',Yset_Point);
            in = in.setVariable('Zset_Point',Zset_Point);
            
            out = sim(in);
            x_feedback= out.xFB.Data(end);
            y_feedback = out.yFB.Data(end);
            z_feedback = out.zFB.Data(end);
            x_feedback = num2str(x_feedback);
            app.XEditField.Value = x_feedback ;
            y_feedback = num2str(y_feedback);
            app.YEditField.Value = y_feedback ;
            z_feedback = num2str(z_feedback);
            app.ZEditField.Value = z_feedback ;
            
            x_setpoint = out.xSP.Data(end);
            y_setpoint = out.ySP.Data(end);
            z_setpoint = out.zSP.Data(end);
            x_setPoint = num2str(x_setpoint);
            y_setpoint = num2str(y_setpoint);
            z_setpoint = num2str(z_setpoint);
            app.XEditField_2.Value = x_setPoint ; 
            app.YEditField_2.Value = y_setpoint ; 
            app.ZEditField_2.Value = z_setpoint ;


            joint1_FB = out.Joint1.Data(end);
            joint2_FB = out.Joint2.Data(end);
            joint3_FB = out.Joint3.Data(end);
            joint4_FB = out.Joint4.Data(end);
            app.Joint1EditField.Value = joint1_FB ;
            app.Joint2EditField.Value = joint2_FB ;
            app.Joint3EditField.Value = joint3_FB ;
            app.Joint4EditField.Value = joint4_FB ;
            t = 0 : 0.1 : 10 ;
             plot(app.UIAxes_3,out.ScopeData1.time,out.ScopeData1.signals.values);
             plot(app.UIAxes,out.ScopeData2.time,out.ScopeData2.signals.values);
             plot(app.UIAxes_4,out.ScopeData3.time,out.ScopeData3.signals.values);
             plot(app.UIAxes_2,out.ScopeData4.time,out.ScopeData4.signals.values);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'joint2 Response')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'angle')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 360];
            app.UIAxes.YTick = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'joint4 Response')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'angle')
            app.UIAxes_2.XLim = [0 10];
            app.UIAxes_2.YLim = [0 360];
            app.UIAxes_2.YTick = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'joint1 Response')
            xlabel(app.UIAxes_3, 't')
            ylabel(app.UIAxes_3, 'angle')
            app.UIAxes_3.XLim = [0 10];
            app.UIAxes_3.YLim = [0 360];
            app.UIAxes_3.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_3.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UIAxes_3.YTick = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'joint3 Response')
            xlabel(app.UIAxes_4, 't')
            ylabel(app.UIAxes_4, 'angle')
            app.UIAxes_4.XLim = [0 10];
            app.UIAxes_4.YLim = [0 360];
            app.UIAxes_4.YTick = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.joint1SliderLabel = uilabel(app.UIFigure);
            app.joint1SliderLabel.Text = 'joint1';
            app.joint1Slider = uislider(app.UIFigure);
            app.joint1Slider.Limits = [0 360];
            app.joint1Slider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.joint2SliderLabel = uilabel(app.UIFigure);
            app.joint2SliderLabel.Text = 'joint2';
            app.joint2Slider = uislider(app.UIFigure);
            app.joint2Slider.Limits = [0 360];
            app.joint2Slider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.joint3SliderLabel = uilabel(app.UIFigure);
            app.joint3SliderLabel.Text = 'joint3';
            app.joint3Slider = uislider(app.UIFigure);
            app.joint3Slider.Limits = [0 360];
            app.joint3Slider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.joint4SliderLabel = uilabel(app.UIFigure);
            app.joint4SliderLabel.Text = 'joint4';
            app.joint4Slider = uislider(app.UIFigure);
            app.joint4Slider.Limits = [0 360];
            app.joint4Slider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.Joint1EditFieldLabel = uilabel(app.UIFigure);
            app.Joint1EditFieldLabel.Text = 'Joint1';
            app.Joint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.UIFigure);
            app.Joint2EditFieldLabel.Text = 'Joint2';
            app.Joint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.UIFigure);
            app.Joint3EditFieldLabel.Text = 'Joint3';
            app.Joint3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.UIFigure);
            app.Joint4EditFieldLabel.Text = 'Joint4';
            app.Joint4EditField = uieditfield(app.UIFigure, 'numeric');
            app.FeedBackLabel = uilabel(app.UIFigure);
            app.FeedBackLabel.Text = 'FeedBack';
            app.SetPointLabel = uilabel(app.UIFigure);
            app.SetPointLabel.Text = 'SetPoint';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'text');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Joint1EditField_2Label = uilabel(app.UIFigure);
            app.Joint1EditField_2Label.Text = 'Joint1';
            app.Joint1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditField_2Label = uilabel(app.UIFigure);
            app.Joint2EditField_2Label.Text = 'Joint2';
            app.Joint2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditField_2Label = uilabel(app.UIFigure);
            app.Joint3EditField_2Label.Text = 'Joint3';
            app.Joint3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditField_2Label = uilabel(app.UIFigure);
            app.Joint4EditField_2Label.Text = 'Joint4';
            app.Joint4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end